classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        ColorAWBPanel  matlab.ui.container.Panel
        EDeltaELabel   matlab.ui.control.Label
        EditField_6    matlab.ui.control.EditField
        EditField_5    matlab.ui.control.EditField
        EditField_3    matlab.ui.control.EditField
        EditField_2    matlab.ui.control.EditField
        EditField      matlab.ui.control.NumericEditField
        EButton        matlab.ui.control.Button
        Button_7       matlab.ui.control.Button
        Button_6       matlab.ui.control.Button
        Button_5       matlab.ui.control.Button
        Button_4       matlab.ui.control.Button
        Button_3       matlab.ui.control.Button
        Button_2       matlab.ui.control.Button
        Button         matlab.ui.control.Button
        ELabel         matlab.ui.control.Label
        Label_2        matlab.ui.control.Label
        Label          matlab.ui.control.Label
        UIAxes_3       matlab.ui.control.UIAxes
        UIAxes_2       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        pathname1
        pathname2
        pathname3
        pathname4
        pathname5
        gres
        duibiaotu
        daicetu
        ave1
    end
    methods (Access = private)

        
        function results = func(app)
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: Button, EditField
        function ButtonPushed(app, event)
            app.pathname1=uigetdir;
            if isequal(app.pathname1,0)
                errordlg("没有选中文件","错误");
            else 
                app.EditField_2.Value=app.pathname1;
            end
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            disp(app.pathname1);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.pathname2=uigetdir;
            if isequal(app.pathname2,0)
                errordlg("没有选中文件","错误");
            else
                app.EditField_3.Value=app.pathname2;
            end
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            disp(app.pathname2);
        end

        % Callback function: Button_3, EditField_6
        function Button_3Pushed(app, event)
            scale   = 0.1;      % downscale

% parameters for bilateral filter (denoising)
            w       = 5;        % half-width
            sigma_c = 5;        % standard deviations of color
            sigma_s = 5;        % standard deviations of space

% parameters for edge detection
            extRange = 4;       % edge extension to improve edge detection
            lengthTH = 10;      % length above this number is long ontour

% parameters for mean shift (KEY parameter)
            msradius = 5;       % spectral radius for mean shift on sampled color space

% parameters for region cleaning
            areaTH.large = 500; % area above this number is large region
            areaTH.small = 200; % area blow this number is small region

%% read color image1
            base_path=[app.pathname1,'\'];
            ext1='*.jpg';
            ext2='*.png';
            files1=(dir([base_path,ext1]))';
            files2=(dir([base_path,ext2]))';
            files=[files1,files2];
            names={files.name};
            for path=1:1:length(names)           
                file = names{path};
                file_name = file(1:4);               
                newfolder=[app.pathname2,'\',file_name];
                mkdir(newfolder);
            end
            for path=1:1:length(names)
                full_path = [base_path, names{path}];
                file = names{path};
                file_name = file(1:4);
                generate_name = file(1:6);
                ori_img  = imread(full_path);
                ori_img = imresize(ori_img, scale); % scale the image
                [H,W,C] = size(ori_img);
                %figure,imshow(ori_img);
        %% image denoising (shiftableBF for O(1) version)2
                lab_img = colorspace('lab<-RGB', ori_img);
                Lcolumn = lab_img(:,:,1); % [  0 - 100]
                Acolumn = lab_img(:,:,2); % [-50 -  50]
                Bcolumn = lab_img(:,:,3); % [-50 -  50]
    %Lcolumn = bilateral_filter(Lcolumn,w,sigma_c,sigma_s);
                Lcolumn = shiftableBF(Lcolumn,sigma_s,sigma_c,w,0.01);
                lab_denoise = cat(3,Lcolumn,Acolumn,Bcolumn);
                lab_data = reshape(lab_denoise,H*W,C);
                rgb_denoise = colorspace('RGB<-lab', lab_denoise);   
                %figure, imshow(rgb_denoise)
    %% structured edge detection3
                %Need Piotr's Computer Vision Matlab Toolbox
                setParametersSED;
                edge_map = edgesDetect(ori_img,model);
                %figure, imshow(1-edge_map);
    % edge extension
                bin_edge_map = edgeExtension(edge_map>0.1, extRange);
                [edgelist, labelededgeim] = edgelink(bin_edge_map, lengthTH);
    %% Contour-guided Color Palette4
    % Color Palette Generation
                tic;
                [long_conts_map,sampledColor] = findSampledColor(lab_denoise, edgelist, lengthTH);
                t1 = toc;
                %figure, imshow(1-long_conts_map);
                colorPalette = MeanShiftCluster(sampledColor', msradius)';
    % quantized image
                Dist = zeros(H*W,size(colorPalette,1));
                for i = 1:size(colorPalette,1)
                    Dist(:,i) = sum((lab_data - colorPalette(repmat(i,H*W,1),:)).^2, 2);
                end
                [~, labels] = min(Dist, [], 2);
                [~, color_segment] = display_color_seg(im2double(ori_img), labels);
                %figure, imshow(color_segment);
                tic;
    % Post-Processing
                label_map = reshape(labels,H,W);%cleanupregions(reshape(labels,H,W), 0, 4);
                [newlabel_map,seg_obj] = aggreg_regions(label_map, rgb_denoise, long_conts_map, lab_data, areaTH);
                [bound_segment, color_segment] = display_color_seg(im2double(ori_img), newlabel_map(:));
                imwrite(color_segment,[app.pathname2,'\' , file_name , '\' , generate_name,'.png']);

                %imwrite(color_segment,[newfolder,'\' , generate_name,'.png']);
                %t2 = toc;
                %saveas( gcf, [app.pathname2,'\' , file_name , '\' , generate_name] ,'jpg' );
            end
            app.EditField_6.Value='完成!';
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.pathname3=uigetdir;
            if isequal(app.pathname3,0)
                errordlg("没有选中文件","错误");
            else
                app.EditField_5.Value=app.pathname3;
            end
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            disp(app.pathname3);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            base_path = [app.pathname3,'\'];
            ext='*.png';
            files2=dir([base_path,ext]); 
            names={files2.name};
            if length(names)~=2
                errordlg("没有选中文件","错误");
            end
            tmp = 0;
            list = {};
            num = 1;
            app.gres = {};
            center={};
            nflag = 0;
            for pic = 32:-2:2
                for path=1:1:length(names)
                    full_path = [base_path, names{path}];  %生成图片路径
                    img = imread(full_path);                %读取三张图片
                    img = imresize(img,[320,320]);          %压缩图片为统一大小       
                    h0 = floor(320/pic);                         %定义框的高
                    w0 = floor(320/pic);                         %定义框的宽
                    flag = 1;
                    for l =1:pic-1
                        for k =1:pic-1
                            matrix = img(h0*k:h0*(k+1),w0*l:w0*(l+1),:);
                            temp0 = matrix(2 ,2, 1);
                            temp1 = matrix(2 ,2, 2);
                            temp2 = matrix(2 ,2, 3);
                            [rows, cols,c2]= size(matrix);
                            for i = 1:rows
                                for j = 1:cols
                                    if (temp0 ~= matrix(i,j,1) || temp1 ~=matrix(i,j,2) || temp2 ~= matrix(i,j,3))
                                        flag = 0;
                                        break;
                                    end
                                end
                            end
                            if (flag == 1)
                                list{tmp+1} = [w0*k,h0*l];
                                tmp = tmp+1;
                            end
                            flag = 1;
                        end
                    end
                end
                tmp = 0;
            
                res = {};
                v=1;
                [r,t]=size(list);
            
                for i = 1:t
                    coordinate=list{i};
                    for j =i+1:t
                        if isequal(list{j},coordinate)
                            v = v + 1;
                            nflag = 1;
                            break;
                        end
                    end
                    if v == 2
                        res{end+1}=coordinate;
                        tmp = tmp+1;
                        v=1;
                    end
                end
                [a,d]=size(res);
                for i=1:d
                    cen=res{1,i};
                    if(cen(1)>90 && cen(1)<180 && cen(2)>130 && cen(2)<230 && pic==32)
                        center{end+1}=res{1,i};
                    else
                        continue;
                    end
                end
            %     if pic == 6
            %         break;
            %     end
                if nflag == 0
                    pic = pic+2;
                    break;
                end
                tmp=0;
                app.gres = res;
                list = [];
                nflag = 0;
                
            end
            
            for path=1:1:length(names)
                full_path = [base_path, names{path}];
                img = imread(full_path);                
                img = imresize(img,[320,320]);               %取图片的垂直高度和水平宽度和通道数
                h0 = floor(320/pic);                         %定义框的高
                w0 = floor(320/pic); 
                [m,n]=size(app.gres);
                for i=1:1:n
                    g= app.gres{1,i};
                    result = imcrop(img,[g(2),g(1),w0,h0]);
                    imwrite(result,[base_path, num2str(i),full_path(end-9:end-4) , '.jpg']);
                    [state,output] = draw_rect(img,[g(1), g(2)],[w0,h0],0);
                    imwrite(output,[base_path,num2str(i), full_path(end-9:end-4),'_result','.jpg']);
                end
                [c,x]=size(center);
                for i=1:1:x
                    h = center{1,i};
                    result1 = imcrop(img,[h(2),h(1),10,10]);
                    imwrite(result1,[base_path, '中心',num2str(i),full_path(end-9:end-4) , '.jpg']);
                    [state1,output1] = draw_rect(img,[h(1), h(2)],[10,10],0);
                    imwrite(output1,[base_path, '中心',num2str(i), full_path(end-9:end-4),'_result','.jpg']);
                end
            end
        end

        % Callback function: Button_6, UIAxes_3
        function Button_6Pushed(app, event)
            [filename4,app.pathname4]=uigetfile('*.jpg');
            if isequal(filename4,0) || isequal(app.pathname4,0)
                errordlg("没有选中文件","错误");
            else
                app.duibiaotu=strcat(app.pathname4,filename4);
            end
            img1=imread(app.duibiaotu);
            imshow(img1,'Parent',app.UIAxes_3);
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            [filename4,app.pathname4]=uigetfile('*.jpg');
            if isequal(filename4,0) || isequal(app.pathname4,0)
                errordlg("没有选中文件","错误");
            else
                app.duibiaotu=strcat(app.pathname4,filename4);
            end
            img1=imread(app.duibiaotu);
            imshow(img1,'Parent',app.UIAxes);
        end

        % Callback function: Button_7, UIAxes_2
        function Button_7Pushed(app, event)
            [filename5,app.pathname5]=uigetfile('*.jpg');
            if isequal(filename5,0) || isequal(app.pathname5,0)
                errordlg("没有选中文件","错误");
            else
                app.daicetu=strcat(app.pathname5,filename5);
            end
            img2=imread(app.daicetu);
            imshow(img2,'Parent',app.UIAxes_2);
        end

        % Button pushed function: EButton
        function EButtonPushed(app, event)
            ori=double(imread(app.duibiaotu));
            rec=double(imread(app.daicetu));
            rec1 = imcrop(rec,[0,0,4000,3000]);
% ori(:,:,1)=double(101);ori(:,:,2)=double(101);ori(:,:,3)=double(101);
% rec(:,:,1)=double(101);rec(:,:,2)=double(100);rec(:,:,3)=double(100);
            [row,col,dim]=size(ori);
            Labstd=reshape(ori,row*col,3);
            Labsample=reshape(rec1,row*col,3);
            KLCH=[1,1,1];
            res=deltaE2000(Labstd,Labsample, KLCH);
            result=reshape(res,row,col);
            ave=mean(result);
            app.ave1=mean(res);
            app.EditField.Value=app.ave1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ColorAWBPanel = uipanel(app.UIFigure);
            app.ColorAWBPanel.Title = '图像质量评价指标之一——Color & AWB 色差计算 ';
            app.UIAxes_2 = uiaxes(app.ColorAWBPanel);
            title(app.UIAxes_2, '待测图片')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.ButtonDownFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.UIAxes_3 = uiaxes(app.ColorAWBPanel);
            title(app.UIAxes_3, '对标图片')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_3.ButtonDownFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Label = uilabel(app.ColorAWBPanel);
            app.Label.Text = '色彩分割';
            app.Label_2 = uilabel(app.ColorAWBPanel);
            app.Label_2.Text = '选择色块';
            app.ELabel = uilabel(app.ColorAWBPanel);
            app.ELabel.Text = '计算△E';
            app.Button = uibutton(app.ColorAWBPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入文件夹';
            app.Button_2 = uibutton(app.ColorAWBPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '导出文件夹';
            app.Button_3 = uibutton(app.ColorAWBPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '图像分割';
            app.Button_4 = uibutton(app.ColorAWBPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '选择图像组';
            app.Button_5 = uibutton(app.ColorAWBPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '选定色块';
            app.Button_6 = uibutton(app.ColorAWBPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '选择对标图片';
            app.Button_7 = uibutton(app.ColorAWBPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '选择待测图片';
            app.EButton = uibutton(app.ColorAWBPanel, 'push');
            app.EButton.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.EButton.Text = '计算△E';
            app.EditField = uieditfield(app.ColorAWBPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EditField_2 = uieditfield(app.ColorAWBPanel, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.ColorAWBPanel, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_5 = uieditfield(app.ColorAWBPanel, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.ColorAWBPanel, 'text');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.EDeltaELabel = uilabel(app.ColorAWBPanel);
            app.EDeltaELabel.Text = '*△E(Delta-E)，指的是在均匀颜色感觉空间中，人眼感觉色差的测试单位；其数值越小越好，分数越高说明色彩越失真。';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end